classdef Cobblefinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout7                 matlab.ui.container.GridLayout
        REMOVElogButton             matlab.ui.control.Button
        LogCobbleButton             matlab.ui.control.Button
        RefreshButton               matlab.ui.control.Button
        UImssg                      matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        ControlsTab                 matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout8                 matlab.ui.container.GridLayout
        GPSCOMLabel_2               matlab.ui.control.Label
        GPSCOMLamp_2                matlab.ui.control.Lamp
        AntennaLabel                matlab.ui.control.Label
        AntennaCOMLamp_2            matlab.ui.control.Lamp
        RunningLabel                matlab.ui.control.Label
        RunningLamp                 matlab.ui.control.Lamp
        TagSlider                   matlab.ui.control.Slider
        TagSliderLabel              matlab.ui.control.Label
        TagIDLabel                  matlab.ui.control.Label
        PrevioustagButton           matlab.ui.control.Button
        NexttagButton               matlab.ui.control.Button
        RSSIAxes                    matlab.ui.control.UIAxes
        AntennaTab                  matlab.ui.container.Tab
        GridLayout9                 matlab.ui.container.GridLayout
        PausegraphButton            matlab.ui.control.StateButton
        NexttagButton_2             matlab.ui.control.Button
        TagIDLabel_2                matlab.ui.control.Label
        PrevioustagButton_2         matlab.ui.control.Button
        YagiAxes                    matlab.ui.control.UIAxes
        DatalogTab                  matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        ConsoleOutputTextArea       matlab.ui.control.TextArea
        ConsoleOutputTextAreaLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        TESTINGTab                  matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        WalkSwitch                  matlab.ui.control.Switch
        WalkSwitchLabel             matlab.ui.control.Label
        HeadingKnob                 matlab.ui.control.Knob
        HeadingKnobLabel            matlab.ui.control.Label
        ConfigureTab                matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        RSSIdotsCheckBox            matlab.ui.control.CheckBox
        ZoomRadiusmDropDownLabel    matlab.ui.control.Label
        ZoomRadiusmDropDown         matlab.ui.control.DropDown
        SnailtrailsCheckBox         matlab.ui.control.CheckBox
        MoplinesCheckBox            matlab.ui.control.CheckBox
        UISwitchLabel               matlab.ui.control.Label
        UILamp                      matlab.ui.control.Lamp
        GPSCOMListBox               matlab.ui.control.ListBox
        GPSCOMSwitch                matlab.ui.control.Switch
        GPSCOMSwitchLabel           matlab.ui.control.Label
        GPSCOMLamp                  matlab.ui.control.Lamp
        AntennaCOMListBox           matlab.ui.control.ListBox
        AntennaCOMSwitch            matlab.ui.control.Switch
        AntennaCOMSwitchLabel       matlab.ui.control.Label
        AntennaCOMLamp              matlab.ui.control.Lamp
        GPSAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)      
        % General variables
        DSP % stuct containing boolean vars to enable display  of GUI features
        datalog % struct containing vars to save the data

        % Timers (and their associated variables)
        % __________
        antCOMtmr % Antenna data aquisition

        antenna % Serial obj
        antCOM % COM#
        gps % serial obj
        gpsCOM % COM
        taglist % hex list of aRFID tags to listen for
        numtags % length(taglist)
        tagflags % length(taglist) - boolean values for flagged tags
        RSSI % Signal strength
        
        % __________
        uitmr

        GPScord % GPS position
        GPStime % GPS UTC time
        MOPlat % MOP Latitudes seperated by NaN values
        MOPlon % MOP longitudes seperated by NaN values
        snail % Snail trail data
        GPSplot % GPS plot struct
        mapoffset % Zoom scale (in degrees)
        RSSIscat % Scatterplot signal strength data
        YagiRSSI % plot antenna RSSI over time
        tagfocus % highlight RSSI and show scatterplot of this tag index

        % __________
        wlktmr %test %debug

        heading % Direction to walk

        % __________
        % minitmrs (singleshot)
        refreshtmr % a singleShot timer to run UIupdate 1 time
        UIdispmssg % display a message to the user
    end
    
    methods (Access = private)     
        %% UI Update
        function UIupdate(app,src,~,~) %(app, obj, event, string_arg)
            % callback function for uitmr to update screen elements

            %             tic %stats

            % 'snail' - display snail trails (previous GPS line)
            if app.DSP.snail
                app.snail=[app.GPScord;app.snail(1:end-1,:)];
                set(app.GPSplot.snail,'XData',app.snail(:,2),'YData',app.snail(:,1))
            else
                set(app.GPSplot.snail,'XData',[],'YData',[])
            end

            % Log RSSI 
            if ~app.datalog.refresh % check for user refresh
                app.RSSIscat=[app.GPScord(1),app.GPScord(2),app.RSSI';app.RSSIscat(1:end-1,:)]; % RSSI scat is the biggest memory hog
                %current
                if app.DSP.RSSIdots % (display signal strength at GPS coordinate)
                    set(app.GPSplot.RSSIdot,'Xdata',app.RSSIscat(:,2),'Ydata',app.RSSIscat(:,1),'SizeData',app.RSSIscat(:,app.tagfocus+2)+1); %.^1.5 exponent factor for short range testing
                end
            end
            % RSSI bar graph
            set(app.GPSplot.RSSI,'XData',[1:1:app.numtags],'YData',app.RSSI); % set bar graph to RSSI data
            app.GPSplot.RSSI.YData(app.tagflags) = repmat(160,sum(app.tagflags,'all'),1); % change all tagged cobbles to RSSI=160
            app.GPSplot.RSSI.CData(:,:) = repmat([0.2, 0.5, 0.7410].*1.2,app.numtags,1); %test set all to blue
            app.GPSplot.RSSI.CData(app.tagflags,:) = repmat([0.4660, 0.6740, 0.1880],sum(app.tagflags,'all'),1); % set flagged tags green
            app.GPSplot.RSSI.CData(app.tagfocus,:) = [0.8500, 0.3250, 0.0980]; % highlight tagfocus in orange

            % Set map limits
            lat=[app.GPScord(1)-app.mapoffset,app.GPScord(1)+app.mapoffset];
            lon=[app.GPScord(2)-app.mapoffset,app.GPScord(2)+app.mapoffset];
            ylim(app.GPSAxes,lat)
            xlim(app.GPSAxes,lon)

            % Plot GPS cord
            set(app.GPSplot.Xmarker,'XData',app.GPScord(2),'YData',app.GPScord(1))
            set(app.GPSplot.Omarker,'XData',app.GPScord(2),'YData',app.GPScord(1))

            % Enable GPS lamp
            taskEx = get(src,'TasksExecuted');
            if mod(taskEx,2)
                app.UILamp.Enable='on';
                app.UILamp.Color='g';
            else
                app.UILamp.Enable='off';
            end
            % Transfer lamp status to main page
            app.AntennaCOMLamp_2.Color=app.AntennaCOMLamp.Color;
            app.GPSCOMLamp_2.Color=app.GPSCOMLamp.Color;

%             app.UITable.Data=app.RSSIscat; %test - display datalog in
%             UItable.  (should only display logged cobbles)
            
            % Yagi Antenna plot
            if ~app.DSP.yagigraph
                app.YagiRSSI=[app.RSSI';app.YagiRSSI(1:end-1,:)]; % set brief RSSI history
            end
            yagipltfocus=app.YagiRSSI(:,app.tagfocus); 
            yagipltfocusavg=yagipltfocus;
            yagipltfocusavg(yagipltfocusavg==0)=nan;
            yagipltfocusavg=movmean(yagipltfocusavg,3,'omitnan');
        
            set(app.GPSplot.antenna,'XData',[1:size(app.YagiRSSI,1)],'YData',yagipltfocus,'Color',[0 0.4470 0.7410]); % set plot to RSSI data
            set(app.GPSplot.antennaavg,'XData',[1:size(app.YagiRSSI,1)],'YData',yagipltfocusavg,'LineWidth',3,'Color',[0.8500 0.3250 0.0980]); % moving average

            off=0.3;
            meanie=mean(yagipltfocusavg,'omitnan');
            ylim(app.YagiAxes,[meanie*(1-off), meanie*(1+off)])
%             ylim(app.YagiAxes,[mean(yagipltfocusavg)*0.95, 160])

            app.RSSI(:)=0; %clear RSSI once plotted

            % Running
            if ~app.datalog.refresh % user did NOT Refresh
                app.RunningLamp.Color='g';
                
                % alternate lamp state
                if mod(app.GPStime(15),2) %check if UTC seconds are even or odd
                    app.RunningLamp.Enable='on';
                    app.RunningLamp.Color='g';
                else
                    app.RunningLamp.Enable='off';
                end
                app.RunningLabel.Text='logging!';
            else % user DID refresh
                app.datalog.refresh=false;
                app.RunningLamp.Color='r';
                app.RunningLamp.Enable='off';
                app.RunningLabel.Text='NOT logging';
            end

%             scroll(app.ConsoleOutputTextArea, 'bottom')
%             scroll(app.UITable, 'bottom')

            drawnow limitrate; %limitrate critical (improves stability)

%             % play sound
%             dur=0.1;
%             [a,~]=playchime(300,dur);
%             [b,fs]=playchime(500,dur);
%             [c,fs]=playchime(700,dur);
%             [d,fs]=playchime(900,dur*0.9);
%             
%             sound([c,d],fs)

%             toc %stats
        end


        %% walking test
        function walktest(app,~,~,~)
            %test that spoofs GPS coordinates to test UIupdate

            walkspeed=5.3666e-07*1; % deg/s **See Testcars.m for calc (Lat/Lon to meters)

            app.GPScord(1)=app.GPScord(1)-(walkspeed*cosd(app.heading));
            app.GPScord(2)=app.GPScord(2)-(walkspeed*sind(app.heading));
        end


        %% lat2meters
        function degrees=meters2GPS(~,Lat,meters)
            lat_m=111e3;
            m2gps=lat_m*cosd(Lat);

            degrees=meters/m2gps;            
        end

        %% Antenna Data Aquisition
        function readant(app,src,~,~)
            a=readline(src);
            a=a{1}(2:end);
            
            % Interpret Serial & output data
            RSSI_OFFSET=255;

            [A,num_elements] = sscanf(a,'%2x%6x01',2); % parse hex to RSI & tag ID
            index = A(2) - app.taglist(1) + 1; %matlab arrays are one based,not zero based
            if num_elements==2 && index <= app.numtags && index > 0
                app.RSSI(index)=RSSI_OFFSET-A(1);
            end
        end

        %% GPS Data Aquisition
        function readgps(app,src,~,~)
            scandata=readline(src);
            
            % Interpret GPS information
            gpssersplit=strsplit(scandata,','); % seperate by comma

            disp(gpssersplit) %test %flag

            if gpssersplit{1}=="$GPGGA"
                % Date & time
                app.GPStime=[datestr(now,'yyyymmdd'),'T',gpssersplit{2},'Z'];
            
                % GPS Lat/Lon
                if gpssersplit{4}=="N"
                    app.GPScord(1)=str2double(gpssersplit{3}(1:2)) + str2double(gpssersplit{3}(3:13))/60;
                elseif gpssersplit{4}=="S"
                    app.GPScord(1)=-str2double(gpssersplit{3}(1:2)) + str2double(gpssersplit{3}(3:13))/60;
                else
                    app.GPScord(1)=nan;
                    telluser(app,"GPS line read error",1)
                end
                if gpssersplit{6}=="W"
                    app.GPScord(2)=-str2double(gpssersplit{5}(1:3)) - str2double(gpssersplit{5}(4:14))/60;
                elseif gpssersplit{6}=="E"
                    app.GPScord(2)=str2double(gpssersplit{5}(1:3)) + str2double(gpssersplit{5}(4:14))/60;
                else
                    app.GPScord(2)=nan;
                    telluser(app,"GPS line read error",1)
                end
            else
                telluser(app,"GPS line read error",1)
                disp(src)
                app.GPScord(:)=nan;
            end

            start(app.uitmr) % run UIupdate once GPS fix aquired
        end

        %% Update taglabel
        function updatetaglabel(app)
            text = sprintf('Tag ID: %d\n(Tag # %d)',app.taglist(app.tagfocus),app.tagfocus);
            app.TagIDLabel.Text=text;
            app.TagIDLabel_2.Text=text;
        end

        %% Set Refresh State
         function setrefreshstate(app,src,~,~)
            app.datalog.refresh=true;
         end

         %% Console mssg log
         function consolemssg(app,src,~,~)
            app.ConsoleOutputTextArea.Value=[app.ConsoleOutputTextArea.Value; datestr(datetime('now'),30), '>> ', app.UImssg.UserData];
            app.UImssg.Visible=true;
         end

         %% UI mssg fade
         function UImssgfade(app,src,~,~)
            app.UImssg.Visible=false;
            app.UImssg.Text='';
            app.UImssg.UserData='';
         end

         %% Tell user fcn
         function telluser(app,mssg,dsp)
             % show user a UImssg (dsp=true) or log to console (dsp=false)
            arguments
                app
                mssg {mustBeText}
                dsp (1,1) logical
            end
            
            if isequal(get(app.UIdispmssg,'Running'),'on')
                stop(app.UIdispmssg);
            end
            if dsp
                app.UImssg.Text=mssg;
                app.UImssg.UserData=mssg;
            else
                app.UImssg.UserData=mssg;
            end
            start(app.UIdispmssg);
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Layout parameters
            debug=false; %enter debug mode (CTRL+F "if debug")
            if debug
                app.UIFigure.WindowState='normal'
                dbstop if error
            else
                app.UIFigure.WindowState='maximized'
            end
            app.UIFigure.Units="normalized"

            % Check Standalone app
            if isdeployed
%                 files = matlab.apputil.getInstalledAppInfo; % get
%                 deployed folder
%                 [path,~,~] = fileparts(files(1).location);
            else
%                 addpath(genpath('C:\Users\ccblack\Documents\Carson\Projects\IOD_activeRFID')) % ensure required files are passed with the compiler
% turn off when exporting
            end

            %% Startup Code
            app.DSP=struct('snail',app.SnailtrailsCheckBox.Value,...
                'mop',app.MoplinesCheckBox.Value,...
                'RSSIdots',app.RSSIdotsCheckBox.Value,...
                'yagigraph',app.PausegraphButton.Value);
            app.heading = app.HeadingKnob.Value;

            % Fix GUI elements
            app.AntennaCOMListBox.Items=cellstr(serialportlist('all'));
            app.AntennaCOMListBox.Value={}; %flag - replace with default COM value

            app.GPSCOMListBox.Items=cellstr(serialportlist('all'));
            app.GPSCOMListBox.Value={}; %flag - replace with default COM value


            % RSSI
            % Import tag list
            [fid, msg]=fopen('taglist.txt','r');
            if fid < 0
                error('Failed to open file "%s" because: "%s"', filename, msg);
            end
            C = textscan(fid,'%s');
            app.taglist = hex2dec(C{1}); % aquire tag IDs
            fclose(fid);
            app.numtags=length(app.taglist); % # of tags tracking
            app.RSSI = zeros(app.numtags,1); % Initialize RSSI to zeros
            app.tagflags=logical(~ones(app.numtags,1)); % Initialize tagflags to FALSE
            app.YagiRSSI=zeros(45,app.numtags); %current
            
            % Fix Tag GUI elements
            app.tagfocus=app.TagSlider.Value;
            text = sprintf('Tag ID: %d\n(Tag # %d)',app.taglist(app.tagfocus),app.tagfocus);
            app.TagIDLabel.Text=text;
            app.TagIDLabel_2.Text=text;
            app.TagSlider.Limits=[1 app.numtags];


            %  KMl
            [app.MOPlon,app.MOPlat]=MOPelementreducer('MOPs_SD_County.kml'); % Load MOP lines
            app.GPScord=[32.866354602512274, -117.25400047613265]; %[Lat, Lon] %test -Fix static GPS cord
            app.mapoffset= meters2GPS(app,app.GPScord(1),str2double(app.ZoomRadiusmDropDown.Value));
            app.snail=nan(14400,2);
            app.snail(1,:)=app.GPScord; % Initialize snail trail
            app.snail=[app.GPScord;app.snail(1:end-1,:)];
            app.snail=[[nan,nan];app.snail(1:end-1,:)]; % prevent any "snail jumps"


            % _____ Initialize graphs _____
            app.GPSplot=struct('Xmarker',{scatter(app.GPSAxes,[],[])},...
                'Omarker',{scatter(app.GPSAxes,[],[])},...
                'MOP',{plot(app.GPSAxes,[],[])},...
                'snail',{plot(app.GPSAxes,[],[])},...
                'RSSIdot',{scatter(app.GPSAxes,[],[])},...
                'RSSI',{bar(app.RSSIAxes,[])},...
                'antenna',{plot(app.YagiAxes,[])},...
                'antennaavg',{plot(app.YagiAxes,[])});
%             app.GPSplot.RSS,I2=bar(app.RSSIAxes,[]); %RSSI2

            % RSSI histogram bar graph
%             app.GPSplot.RSSI2=bar(app.RSSIAxes,app.taglist,app.RSSI); %RSSI2
%             app.GPSplot.RSSI2.FaceColor = 'flat';
            hold(app.RSSIAxes,"on")
            app.GPSplot.RSSI=bar(app.RSSIAxes,app.taglist,app.RSSI);
            app.GPSplot.RSSI.FaceColor = 'flat';
            ylim(app.RSSIAxes,[0 160])

            % Yagi RSSI vs time graph
            app.GPSplot.antenna=plot(app.YagiAxes,app.YagiRSSI); %current
            hold(app.YagiAxes,"on")
            app.GPSplot.antennaavg=plot(app.YagiAxes,app.YagiRSSI);

            % GPS plot
            app.GPSplot.Xmarker= scatter(app.GPSAxes,app.GPScord(2),app.GPScord(1),200,'blue','x','LineWidth',1); % Position marker
            hold(app.GPSAxes,'on')
            app.GPSplot.Omarker= scatter(app.GPSAxes,app.GPScord(2),app.GPScord(1),200,'blue','o','LineWidth',1); % Position marker

            % 'mop' - display mop lines
            if app.DSP.mop
                app.GPSplot.MOP=plot(app.GPSAxes,app.MOPlon,app.MOPlat,'red');
            end

            % 'snail' - display snail trails (previous GPS line)
            if app.DSP.snail
                app.snail(end+1,:)=app.GPScord;
                app.GPSplot.snail=plot(app.GPSAxes,app.snail(:,2),app.snail(:,1),'green');
            end

            % 'RSSI dots' - signal strength indicators tied to GPS pos.
            app.RSSIscat=ones(14400,2+app.numtags);
            app.RSSIscat(1,1:2)=app.GPScord;
            app.GPSplot.RSSIdot= scatter(app.GPSAxes,app.RSSIscat(1,2),app.RSSIscat(1,1),app.RSSIscat(1,3),[0.8500, 0.3250, 0.0980],'o','LineWidth',0.5);

            %% Datalog startup
            app.datalog=struct('filename',[datestr(datetime('now'),'yyyymmdd'),'_aRFIDcobbleLog'],...
                'refresh',false,...
                'foldername','data',...
                'filepath',fullfile('data',[datestr(datetime('now'),'yyyymmdd'),'_aRFIDcobbleLog',]));
            mkdir(app.datalog.foldername);
            nowutc=[datestr(datetime('now', 'TimeZone','Z'),30),'Z'];
            datalogheadder=sprintf('STARTUP@%s UTCtime, Tag_ID#, Lat, Lon, notes', nowutc);
            writematrix(datalogheadder,app.datalog.filepath,'Delimiter','tab','WriteMode','append');                

            %% Startup timers (DO THIS LAST)
            pause(0.5) % ensure proper loading

            % refresh timer (singleshot)
            app.refreshtmr=timer('ExecutionMode','singleShot',...
                'StartFcn',{@app.setrefreshstate,app},...
                'TimerFcn',{@app.UIupdate,app});

            % console message timer
            app.UIdispmssg=timer('ExecutionMode','singleShot',...
                'StartFcn',{@app.consolemssg,app},...
                'StartDelay',2,...
                'TimerFcn',{@app.UImssgfade,app});
            
            %GPS timer
            gpsserialuicontroller=true; %FLAG sync UIupdate with GPS aquisition rather than a periodic timer.  **CHANGE readGPS if you want to change this value
            if gpsserialuicontroller
                app.uitmr=timer('ExecutionMode', 'singleShot',...
                'TimerFcn', {@app.UIupdate,app},...
                'StartFcn',"disp('start UI timer')",...
                'StopFcn',"disp('stop UI timer')",...
                'ErrorFcn',"disp('UI timer erorr!')");
            else
                 app.uitmr=timer('ExecutionMode', 'FixedRate', 'Period', 1, ...
                'TimerFcn', {@app.UIupdate,app}, ...
                'StartFcn',"disp('start UI timer')",...
                'StopFcn',"disp('stop UI timer')",...
                'ErrorFcn',"disp('UI timer erorr!')");
                start(app.uitmr)
            end

            % Walk timer %test
            app.wlktmr=timer('ExecutionMode', 'FixedRate', 'Period', 0.05, ...
                        'TimerFcn', {@app.walktest,app}, ...
                        'StartFcn',"disp('start walk timer')",...
                        'StopFcn',"disp('stop walk timer')",...
                        'ErrorFcn',"disp('Walk timer erorr!')");

            %% Debug startup

            %flag - Only allowed user actions are permitted below this
            %line, variable definitions should be assigned before timer
            %functions start.

            if debug
                % Start RSSI plotting (connect to atenna with known port)
                if ismember("COM4",serialportlist('available'))
                    app.AntennaCOMListBox.Value="COM4";
                    app.AntennaCOMSwitch.Value="On";
                    AntennaCOMListBoxValueChanged(app);
                    AntennaCOMSwitchValueChanged(app);
                end

                % set Zoom
                app.ZoomRadiusmDropDown.Value="1000";
                ZoomRadiusmDropDownValueChanged(app);

                % Start wlktmr
%                 app.WalkSwitch.Value='On';
%                 WalkSwitchValueChanged(app);
            end


            % do not put anything below this line 
            % (account for all variables before starting timers)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(timerfind) % stop all timers
            delete(timerfind) % delete all timers
            
            nowutc=[datestr(datetime('now', 'TimeZone','Z'),30),'Z'];
            datalogheadder=sprintf('SHUTDOWN@%s', nowutc);
            writematrix(datalogheadder,app.datalog.filepath,'Delimiter','tab','WriteMode','append');   
            
            try
                delete(app.antenna) % attempt to delete
                delete(app.gps) % attempt to delete
                delete(app) %current
            catch ME
                disp('unable to properly close antenna COM')
                error(ME.identifier)
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            a=get(app.uitmr,'Running');
            if isequal(a ,'off')
              start(app.uitmr)
            end
            COMrefreshmssg=false;
            
            telluser(app,'Refreshed UI',true)

            antCOMvalue = app.AntennaCOMSwitch.Value;
            GPSCOMvalue = app.GPSCOMSwitch.Value;
            switch antCOMvalue
                case 'On'

                case 'Off'
                    % Refresh list of available COM ports
                    app.AntennaCOMListBox.Items=cellstr(serialportlist('all'));
                    app.AntennaCOMListBox.Value={}; %flag - replace with default COM value
                    telluser(app,'Reset Antenna COM ports',false)
                    COMrefreshmssg=true;
            end
            switch GPSCOMvalue
                case 'On'

                case 'Off'
                     % Refresh list of available COM ports
                    app.GPSCOMListBox.Items=cellstr(serialportlist('all'));
                    app.GPSCOMListBox.Value={}; %flag - replace with default COM value
                    telluser(app,'Reset GPS COM ports',false)
                    COMrefreshmssg=true;
            end
            if COMrefreshmssg
                telluser(app,'Reset COM ports',true)
            end
        end

        % Value changed function: AntennaCOMListBox
        function AntennaCOMListBoxValueChanged(app, event)
            app.antCOM = app.AntennaCOMListBox.Value;
            app.AntennaCOMSwitch.Enable='on';
        end

        % Value changed function: AntennaCOMSwitch
        function AntennaCOMSwitchValueChanged(app, event)
            value = app.AntennaCOMSwitch.Value;
            switch value
                case 'On'
                    % Configure Serial
                    if any(strcmp(serialportlist('available'),app.antCOM))
                        app.antenna=serialport(app.antCOM,9600);
                        configureTerminator(app.antenna,93); % 93 ascii = ']'
                        configureCallback(app.antenna,"terminator",@app.readant);
                    else
                        error('Antenna COM port not available (%s)',app.antCOM);
                    end
                    
                    app.AntennaCOMLamp.Enable='on';
                    app.AntennaCOMLamp.Color='g';
                    app.AntennaCOMListBox.Enable='off';

                    % Transfer lamp status to main page
                    app.AntennaCOMLamp_2.Color=app.AntennaCOMLamp.Color;
                case 'Off'
                    delete(app.antenna);
                    
                    app.AntennaCOMLamp.Enable='off';
                    app.AntennaCOMLamp.Color='r';
                    app.AntennaCOMListBox.Enable='on';

                    % Transfer lamp status to main page
                    app.AntennaCOMLamp_2.Color=app.AntennaCOMLamp.Color;
            end
        end

        % Value changed function: GPSCOMListBox
        function GPSCOMListBoxValueChanged(app, event)
            app.gpsCOM = app.GPSCOMListBox.Value;
            app.GPSCOMSwitch.Enable='on';
        end

        % Value changed function: GPSCOMSwitch
        function GPSCOMSwitchValueChanged(app, event)
            value = app.GPSCOMSwitch.Value;
            switch value
                case 'On'
                    % Configure Serial
                    if any(strcmp(serialportlist('available'),app.gpsCOM))
                        app.gps=serialport(app.gpsCOM,115200);
                        configureTerminator(app.gps,"CR/LF");
                        configureCallback(app.gps,"terminator",@app.readgps);
                    else
                        error('GPS COM port not available (%s)',app.gpsCOM);
                    end
                    
                    % Turn on GPS lamp
                    app.GPSCOMLamp.Enable='on';
                    app.GPSCOMLamp.Color='g';
                    app.GPSCOMListBox.Enable='off';

                    % Turn on UI lamp
                    app.UILamp.Color='g';
                    app.GPSCOMLamp.Enable='on';

                    % Turn on Main Running lamp
                    app.RunningLamp.Color='g';
                    app.RunningLamp.Enable='on';

                    % Transfer lamp status to main page
                    app.GPSCOMLamp_2.Color=app.GPSCOMLamp.Color;
                case 'Off'
                    delete(app.gps);
                    
                    app.GPSCOMLamp.Enable='off';
                    app.GPSCOMLamp.Color='r';
                    app.GPSCOMListBox.Enable='on';

                    app.UILamp.Color='r';
                    app.GPSCOMLamp.Enable='off';

                    app.RunningLamp.Color='r';
                    app.RunningLamp.Enable='off';

                    % Transfer lamp status to main page
                    app.GPSCOMLamp_2.Color=app.GPSCOMLamp.Color;
            end
        end

        % Callback function
        function UISwitchValueChanged(app, event)
            value = app.UISwitch.Value;
            app.snail=[[nan,nan];app.snail(1:end-1,:)];
            switch value
                case 'On'
                    app.RSSI(:)=0; % clear any rollover RSSI that was collected while UI and GPS were off
                    start(app.uitmr)
                    app.UILamp.Enable='on';
                    app.UILamp.Color='g';
                case 'Off'
                    stop(app.uitmr)
                    start(app.refreshtmr); % run a singleshot to turn off datalog
                    app.UILamp.Enable='off';
                    app.UILamp.Color='r';
            end
        end

        % Value changed function: SnailtrailsCheckBox
        function SnailtrailsCheckBoxValueChanged(app, event)
            app.DSP.snail=app.SnailtrailsCheckBox.Value;
            app.snail=[[nan,nan];app.snail(1:end-1,:)];

            if app.DSP.snail
                set(app.GPSplot.snail,'XData',app.snail(:,2),'YData',app.snail(:,1));
            else
                set(app.GPSplot.snail,'XData',[],'YData',[]);
            end
        end

        % Value changed function: MoplinesCheckBox
        function MoplinesCheckBoxValueChanged(app, event)
            app.DSP.mop=app.MoplinesCheckBox.Value;

            if app.DSP.mop
                set(app.GPSplot.MOP,'XData',app.MOPlon,'YData',app.MOPlat);
            else
                set(app.GPSplot.MOP,'XData',[],'YData',[]);
            end
        end

        % Value changed function: RSSIdotsCheckBox
        function RSSIdotsCheckBoxValueChanged(app, event)
           app.DSP.RSSIdots=app.RSSIdotsCheckBox.Value;

           if app.DSP.RSSIdots
                set(app.GPSplot.RSSIdot,'Xdata',app.RSSIscat(:,2),'Ydata',app.RSSIscat(:,1),'SizeData',app.RSSIscat(:,app.tagfocus+2)+1);
            else
                set(app.GPSplot.RSSIdot,'XData',[],'YData',[],'SizeData',[]);
            end
        end

        % Value changed function: ZoomRadiusmDropDown
        function ZoomRadiusmDropDownValueChanged(app, event)
            app.mapoffset= meters2GPS(app,app.GPScord(1),str2double(app.ZoomRadiusmDropDown.Value)/2);
            drawnow
        end

        % Value changed function: WalkSwitch
        function WalkSwitchValueChanged(app, event)
            value = app.WalkSwitch.Value;
            switch value
                case 'On'
                    start(app.wlktmr)
                case 'Off'
                    stop(app.wlktmr)
            end
        end

        % Value changed function: HeadingKnob
        function HeadingKnobValueChanged(app, event)
            app.heading = app.HeadingKnob.Value;
        end

        % Button pushed function: NexttagButton, NexttagButton_2
        function NexttagButtonPushed(app, event)
            if app.tagfocus==app.numtags
                app.tagfocus=1;
            else
                app.tagfocus=app.tagfocus+1;
            end

            % Update label
            updatetaglabel(app)

            app.TagSlider.Value=app.tagfocus;
        end

        % Button pushed function: PrevioustagButton, PrevioustagButton_2
        function PrevioustagButtonPushed(app, event)
            if app.tagfocus==1
                app.tagfocus=app.numtags;
            else
                app.tagfocus=app.tagfocus-1;
            end

            % Update label
            updatetaglabel(app)

            app.TagSlider.Value=app.tagfocus;
        end

        % Value changed function: TagSlider
        function TagSliderValueChanged(app, event)
            app.tagfocus = round(app.TagSlider.Value);

            % Update label
            updatetaglabel(app)
            
        end

        % Button pushed function: LogCobbleButton
        function LogCobbleButtonPushed(app, event)
            checks=false(2,1);
            nowutc=[datestr(datetime('now', 'TimeZone','Z'),30),'Z'];
            checks(1)=all(nowutc(1:end-3)==app.GPStime(1:end-6));
            checks(2)=(str2double(nowutc(end-2:end-1))-str2double(app.GPStime(end-5:end-4))) < 5 ; %GPS fix vs datalog button less than 5 seconds apart
            checks(3)=app.tagflags(app.tagfocus)==false;

            if checks
                disp(app.taglist(app.tagfocus))
                writematrix([app.GPStime ,"", num2str(app.taglist(app.tagfocus)) ,"", num2str(app.GPScord(1)) ,"", num2str(app.GPScord(2)) ,"", "User saved cobble position"],app.datalog.filepath,'Delimiter','tab','WriteMode','append'); %flag save data
                % readability improved by creating column headings in startup code
                mssg=sprintf('Cobble %s Position Recorded',num2str(app.taglist(app.tagfocus)));
                telluser(app,mssg,true);
                app.tagflags(app.tagfocus)=true;
            else
                telluser(app,'unable to save location',true)
            end

            %{
                - get full RSSI spectrum
                - record highlighted RSSI
            %}
        end

        % Value changed function: PausegraphButton
        function PausegraphButtonValueChanged(app, event)
            app.DSP.yagigraph = app.PausegraphButton.Value;
        end

        % Button pushed function: REMOVElogButton
        function REMOVElogButtonPushed(app, event)
            disp(app.taglist(app.tagfocus))
                writematrix([app.GPStime ,"", num2str(app.taglist(app.tagfocus)) ,"", num2str(nan) ,"", num2str(nan) ,"", "User removed logged position"],app.datalog.filepath,'Delimiter','tab','WriteMode','append'); %flag save data
                % readability improved by creating column headings in startup code
                mssg=sprintf('Cobble %s Position REMOVED',num2str(app.taglist(app.tagfocus)));
                telluser(app,mssg,true);
                app.tagflags(app.tagfocus)=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GPSAxes = uiaxes(app.GridLayout);
            title(app.GPSAxes, 'GPS')
            xlabel(app.GPSAxes, 'Longitude')
            ylabel(app.GPSAxes, 'Latitude')
            zlabel(app.GPSAxes, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ControlsTab = uitab(app.TabGroup);
            app.ControlsTab.Title = 'Controls';
            app.GridLayout2 = uigridlayout(app.ControlsTab);
            app.RSSIAxes = uiaxes(app.GridLayout2);
            title(app.RSSIAxes, 'RSSI Histogram')
            xlabel(app.RSSIAxes, 'Tag ID')
            ylabel(app.RSSIAxes, 'Signal Strength')
            zlabel(app.RSSIAxes, 'Z')
            app.NexttagButton = uibutton(app.GridLayout2, 'push');
            app.NexttagButton.ButtonPushedFcn = createCallbackFcn(app, @NexttagButtonPushed, true);
            app.NexttagButton.Text = 'Next tag';
            app.PrevioustagButton = uibutton(app.GridLayout2, 'push');
            app.PrevioustagButton.ButtonPushedFcn = createCallbackFcn(app, @PrevioustagButtonPushed, true);
            app.PrevioustagButton.Text = 'Previous tag';
            app.TagIDLabel = uilabel(app.GridLayout2);
            app.TagIDLabel.Text = 'Tag ID: ';
            app.TagSliderLabel = uilabel(app.GridLayout2);
            app.TagSliderLabel.Text = 'Tag #';
            app.TagSlider = uislider(app.GridLayout2);
            app.TagSlider.Limits = [1 30];
            app.TagSlider.ValueChangedFcn = createCallbackFcn(app, @TagSliderValueChanged, true);
            app.TagSlider.Value = 1;
            app.RunningLamp = uilamp(app.GridLayout2);
            app.RunningLabel = uilabel(app.GridLayout2);
            app.RunningLabel.Text = 'Running';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.AntennaCOMLamp_2 = uilamp(app.GridLayout8);
            app.AntennaLabel = uilabel(app.GridLayout8);
            app.AntennaLabel.Text = 'Antenna';
            app.GPSCOMLamp_2 = uilamp(app.GridLayout8);
            app.GPSCOMLabel_2 = uilabel(app.GridLayout8);
            app.GPSCOMLabel_2.Text = 'GPS';
            app.AntennaTab = uitab(app.TabGroup);
            app.AntennaTab.Title = 'Antenna';
            app.GridLayout9 = uigridlayout(app.AntennaTab);
            app.YagiAxes = uiaxes(app.GridLayout9);
            xlabel(app.YagiAxes, 'time')
            ylabel(app.YagiAxes, 'RSSI')
            zlabel(app.YagiAxes, 'Z')
            app.PrevioustagButton_2 = uibutton(app.GridLayout9, 'push');
            app.PrevioustagButton_2.ButtonPushedFcn = createCallbackFcn(app, @PrevioustagButtonPushed, true);
            app.PrevioustagButton_2.Text = 'Previous tag';
            app.TagIDLabel_2 = uilabel(app.GridLayout9);
            app.TagIDLabel_2.Text = 'Tag ID: ';
            app.NexttagButton_2 = uibutton(app.GridLayout9, 'push');
            app.NexttagButton_2.ButtonPushedFcn = createCallbackFcn(app, @NexttagButtonPushed, true);
            app.NexttagButton_2.Text = 'Next tag';
            app.PausegraphButton = uibutton(app.GridLayout9, 'state');
            app.PausegraphButton.ValueChangedFcn = createCallbackFcn(app, @PausegraphButtonValueChanged, true);
            app.PausegraphButton.Text = 'Pause graph';
            app.DatalogTab = uitab(app.TabGroup);
            app.DatalogTab.Title = 'Datalog';
            app.GridLayout3 = uigridlayout(app.DatalogTab);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Multiselect = 'off';
            app.ConsoleOutputTextAreaLabel = uilabel(app.GridLayout3);
            app.ConsoleOutputTextAreaLabel.Text = 'Console Output';
            app.ConsoleOutputTextArea = uitextarea(app.GridLayout3);
            app.ConsoleOutputTextArea.Value = {'Cobblefinder Startup'};
            app.TESTINGTab = uitab(app.TabGroup);
            app.TESTINGTab.Title = 'TESTING';
            app.GridLayout6 = uigridlayout(app.TESTINGTab);
            app.HeadingKnobLabel = uilabel(app.GridLayout6);
            app.HeadingKnobLabel.Text = 'Heading';
            app.HeadingKnob = uiknob(app.GridLayout6, 'continuous');
            app.HeadingKnob.Limits = [0 360];
            app.HeadingKnob.ValueChangedFcn = createCallbackFcn(app, @HeadingKnobValueChanged, true);
            app.HeadingKnob.Value = 180;
            app.WalkSwitchLabel = uilabel(app.GridLayout6);
            app.WalkSwitchLabel.Text = 'Walk';
            app.WalkSwitch = uiswitch(app.GridLayout6, 'slider');
            app.WalkSwitch.ValueChangedFcn = createCallbackFcn(app, @WalkSwitchValueChanged, true);
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.GridLayout4 = uigridlayout(app.ConfigureTab);
            app.AntennaCOMLamp = uilamp(app.GridLayout4);
            app.AntennaCOMLamp.Enable = 'off';
            app.AntennaCOMSwitchLabel = uilabel(app.GridLayout4);
            app.AntennaCOMSwitchLabel.Text = 'Antenna COM';
            app.AntennaCOMSwitch = uiswitch(app.GridLayout4, 'slider');
            app.AntennaCOMSwitch.ValueChangedFcn = createCallbackFcn(app, @AntennaCOMSwitchValueChanged, true);
            app.AntennaCOMSwitch.Enable = 'off';
            app.AntennaCOMListBox = uilistbox(app.GridLayout4);
            app.AntennaCOMListBox.Items = {'list COM ports'};
            app.AntennaCOMListBox.ValueChangedFcn = createCallbackFcn(app, @AntennaCOMListBoxValueChanged, true);
            app.AntennaCOMListBox.Value = 'list COM ports';
            app.GPSCOMLamp = uilamp(app.GridLayout4);
            app.GPSCOMLamp.Enable = 'off';
            app.GPSCOMSwitchLabel = uilabel(app.GridLayout4);
            app.GPSCOMSwitchLabel.Text = 'GPS COM';
            app.GPSCOMSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GPSCOMSwitch.ValueChangedFcn = createCallbackFcn(app, @GPSCOMSwitchValueChanged, true);
            app.GPSCOMSwitch.Enable = 'off';
            app.GPSCOMListBox = uilistbox(app.GridLayout4);
            app.GPSCOMListBox.Items = {'list COM ports'};
            app.GPSCOMListBox.ValueChangedFcn = createCallbackFcn(app, @GPSCOMListBoxValueChanged, true);
            app.GPSCOMListBox.Value = 'list COM ports';
            app.UILamp = uilamp(app.GridLayout4);
            app.UISwitchLabel = uilabel(app.GridLayout4);
            app.UISwitchLabel.Text = 'UI update';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.MoplinesCheckBox = uicheckbox(app.GridLayout5);
            app.MoplinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MoplinesCheckBoxValueChanged, true);
            app.MoplinesCheckBox.Text = 'Mop lines';
            app.MoplinesCheckBox.Value = true;
            app.SnailtrailsCheckBox = uicheckbox(app.GridLayout5);
            app.SnailtrailsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SnailtrailsCheckBoxValueChanged, true);
            app.SnailtrailsCheckBox.Text = 'Snail trails';
            app.SnailtrailsCheckBox.Value = true;
            app.ZoomRadiusmDropDown = uidropdown(app.GridLayout5);
            app.ZoomRadiusmDropDown.Items = {'50', '100', '200', '500', '750', '1000'};
            app.ZoomRadiusmDropDown.ValueChangedFcn = createCallbackFcn(app, @ZoomRadiusmDropDownValueChanged, true);
            app.ZoomRadiusmDropDown.Value = '200';
            app.ZoomRadiusmDropDownLabel = uilabel(app.GridLayout5);
            app.ZoomRadiusmDropDownLabel.Text = 'Zoom Radius (m)';
            app.RSSIdotsCheckBox = uicheckbox(app.GridLayout5);
            app.RSSIdotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RSSIdotsCheckBoxValueChanged, true);
            app.RSSIdotsCheckBox.Text = 'RSSI dots';
            app.RSSIdotsCheckBox.Value = true;
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.UImssg = uilabel(app.GridLayout7);
            app.UImssg.Visible = 'off';
            app.UImssg.Text = '';
            app.RefreshButton = uibutton(app.GridLayout7, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.LogCobbleButton = uibutton(app.GridLayout7, 'push');
            app.LogCobbleButton.ButtonPushedFcn = createCallbackFcn(app, @LogCobbleButtonPushed, true);
            app.LogCobbleButton.Text = 'Log Cobble';
            app.REMOVElogButton = uibutton(app.GridLayout7, 'push');
            app.REMOVElogButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVElogButtonPushed, true);
            app.REMOVElogButton.Text = 'REMOVE log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cobblefinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end